classdef ShittySpinners < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        AlphaSpinnerLabel  matlab.ui.control.Label
        AlphaSpinner       matlab.ui.control.Spinner
        BetaSpinnerLabel   matlab.ui.control.Label
        BetaSpinner        matlab.ui.control.Spinner
        EditFieldLabel     matlab.ui.control.Label
        EditField          matlab.ui.control.NumericEditField
        EditField2Label    matlab.ui.control.Label
        EditField2         matlab.ui.control.EditField
    end


    properties (Access = private)
        test  = 0 % Description
        test2 = 0 % Description
    end

    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Value changed function: AlphaSpinner
        function AlphaSpinnerValueChanged(app, event)
            app.AlphaSpinner.Editable = 'on';
            app.BetaSpinner.Editable = 'off';
            app.test = app.AlphaSpinner.Value;
            pause(0.1)
            app.BetaSpinner.Value = app.test2;
            app.EditField2.Value = strcat(num2str(app.test),num2str(app.test2));
        end

        % Value changed function: BetaSpinner
        function BetaSpinnerValueChanged(app, event)
            app.AlphaSpinner.Editable = 'off';
            app.BetaSpinner.Editable = 'on';
            app.test2 = app.BetaSpinner.Value;
            pause(0.1)
            app.AlphaSpinner.Value = app.test;
            app.EditField2.Value = strcat(num2str(app.test),num2str(app.test2));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.AlphaSpinnerLabel = uilabel(app.UIFigure);
            app.AlphaSpinnerLabel.Text = 'Alpha';
            app.AlphaSpinner = uispinner(app.UIFigure);
            app.AlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @AlphaSpinnerValueChanged, true);
            app.BetaSpinnerLabel = uilabel(app.UIFigure);
            app.BetaSpinnerLabel.Text = 'Beta';
            app.BetaSpinner = uispinner(app.UIFigure);
            app.BetaSpinner.ValueChangedFcn = createCallbackFcn(app, @BetaSpinnerValueChanged, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = ShittySpinners()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end