classdef USB_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InitiateconnectionButton   matlab.ui.control.Button
        TerminateButton            matlab.ui.control.Button
        WavelengthSpinnerLabel     matlab.ui.control.Label
        WavelengthSpinner          matlab.ui.control.Spinner
        SENDButton                 matlab.ui.control.Button
        SendingLampLabel           matlab.ui.control.Label
        SendingLamp                matlab.ui.control.Lamp
        COMportfoundTextAreaLabel  matlab.ui.control.Label
        COMportfoundTextArea       matlab.ui.control.TextArea
        PortfoundLampLabel         matlab.ui.control.Label
        PortfoundLamp              matlab.ui.control.Lamp
        ConnectionStatusLampLabel  matlab.ui.control.Label
        ConnectionStatusLamp       matlab.ui.control.Lamp
        RefreshButton              matlab.ui.control.Button
        SentDataTextAreaLabel      matlab.ui.control.Label
        SentDataTextArea           matlab.ui.control.TextArea
        DEBUGBLACKButton           matlab.ui.control.Button
        BandwidthSliderLabel       matlab.ui.control.Label
        BandwidthSlider            matlab.ui.control.Slider
    end


    properties (Access = public)
        % All variables used in the program are listed here. The program refers to these by app.[variable]
        s = ' ';             % String to be filled in with port name and settings
        Wavelength = 400;    % Initial value of the specified wavelength
        Column = 1;         % Initial value of the collumn, which will relate to the wavelength
        Bandwidth = 0;       % Initial value of the specified bandwidth
        SerialInfo = ' ';    % String to be filled in with information about connected ports to the pc
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % On startup there are a couple of things we need to do. 
            % First The program closes all previous opened COM-ports that it can find.
            delete(instrfindall);
            % The program applies the right colors to the lights to indicate the initial status.
            app.SendingLamp.Color = [1,0,0];
            app.PortfoundLamp.Color = [1,0,0];
            app.ConnectionStatusLamp.Color = [1,0,0];
            % The program disables buttons we dont want the user to press, since it would be useless.
            app.SENDButton.Enable = 'off';
            app.TerminateButton.Enable = 'off';
            % The program lets the pc sum up all the connceted hardware and filter only the 'serial' information to fill in.
            app.SerialInfo = instrhwinfo('serial');
            % The program now checks if there is any ports found. If so, it is difined in a string including standard settings. 
            % The program now allows users to interact with more fields and buttons. The user is notified by the lightup of the 'port found' light.
            % If there are no ports found, the program limits all useability and starts up with no other option than to refresh the port search.
            if isempty(app.SerialInfo.SerialPorts)
                app.COMportfoundTextArea.Value = '<none>';
                app.COMportfoundTextArea.Enable = 'off';
                app.COMportfoundTextArea.Editable = 'off';
                app.PortfoundLamp.Color = [1,0,0];
                app.InitiateconnectionButton.Enable = 'off';
            else
                app.s = serial(app.SerialInfo.SerialPorts(end),'BAUD',9600);
                app.COMportfoundTextArea.Value = app.SerialInfo.SerialPorts(end);
                app.COMportfoundTextArea.Enable = 'on';
                app.COMportfoundTextArea.Editable = 'off';
                app.PortfoundLamp.Color = [0,1,0];
                app.InitiateconnectionButton.Enable = 'on';
            end
        end

        % Button pushed function: InitiateconnectionButton
        function InitiateconnectionButtonPushed(app, event)
            % The program lets the pc sum up all the connceted hardware and filter only the 'serial' information to fill in.
            app.SerialInfo = instrhwinfo('serial');
            % The program now checks if there is any ports found. If the connections changed and the program cant find any ports, it disables all
            % possibilities to interact.
            % Otherwise, asuming nothing changed, we open he port and are now talking directly to th output.
            if isempty(app.SerialInfo.SerialPorts)
                app.COMportfoundTextArea.Value = '<none>';
                app.COMportfoundTextArea.Enable = 'off';
                app.COMportfoundTextArea.Editable = 'off';
                app.PortfoundLamp.Color = [1,0,0];
                app.InitiateconnectionButton.Enable = 'off';
            else
                fopen(app.s);
                app.InitiateconnectionButton.Enable = 'off';
                app.RefreshButton.Enable = 'off';
                %pause(3)
                app.TerminateButton.Enable = 'on';
                app.SENDButton.Enable = 'on';
                app.ConnectionStatusLamp.Color = [0,1,0];
            end
            
        end

        % Button pushed function: TerminateButton
        function TerminateButtonPushed(app, event)
            % To safely close the devices we send one final 'C' to 'Close' the device
            ttx = 'C';
            fwrite(app.s,ttx);
            % We wait to be sure that all data is sent and then close the port, disable all connection and button useability.
            pause(3)
            fclose(app.s);
            app.RefreshButton.Enable = 'on';
            app.ConnectionStatusLamp.Color = [1,0,0];
            app.SENDButton.Enable = 'off';
            app.TerminateButton.Enable = 'off';
            app.InitiateconnectionButton.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Closing the window makes the program check if there is still ports open. IF so, it closes them and ends. Or it just ends.
            if isempty(app.SerialInfo.SerialPorts)
                delete(app);
            else
                fclose(app.s);
                app.ConnectionStatusLamp.Color = [1,0,0];
                pause(2)
                delete(app);
            end
        end

        % Value changed function: WavelengthSpinner
        function WavelengthSpinnerValueChanged(app, event)
            % Everytime the spiner is changed, the porgram changes the variable
            app.Wavelength = app.WavelengthSpinner.Value;
        end

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)
            app.SerialInfo = instrhwinfo('serial');
            if isempty(app.SerialInfo.SerialPorts)
                app.COMportfoundTextArea.Value = '<none>';
                app.COMportfoundTextArea.Enable = 'off';
                app.COMportfoundTextArea.Editable = 'off';
                app.PortfoundLamp.Color = [1,0,0];
                app.ConnectionStatusLamp.Color = [1,0,0];
                app.InitiateconnectionButton.Enable = 'off';
                app.RefreshButton.Enable = 'on';
                app.SENDButton.Enable = 'off';
                fclose(app.s);
            else
                app.Column = round((17/20)*app.Wavelength-340);
                ttx = strcat('W',num2str(app.Column),'B',num2str(app.Bandwidth));
                fwrite(app.s,ttx)
                app.SentDataTextArea.Value = ttx;
                app.SendingLamp.Color = [1,0.8,0];
                pause(0.25)
                app.SendingLamp.Color = [1,0.5,0];
                pause(0.25)
                app.SendingLamp.Color = [1,0.8,0];
                pause(0.25)
                app.SendingLamp.Color = [1,0.5,0];
                pause(0.25)
                app.SendingLamp.Color = [1,0.8,0];
                pause(0.25)
                app.SendingLamp.Color = [1,0.5,0];
                pause(0.25)
                app.SendingLamp.Color = [1,0.8,0];
                pause(0.25)
                app.SendingLamp.Color = [1,0.5,0];
                pause(0.25)
                app.SendingLamp.Color = [1,0,0];
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            
            app.SerialInfo = instrhwinfo('serial');
            % maak een verbinding met de arduino
            % het nummer van de COM poort kan varieren!!! vaak de hoogste
            if isempty(app.SerialInfo.SerialPorts)
                app.COMportfoundTextArea.Value = '<none>';
                app.COMportfoundTextArea.Enable = 'off';
                app.COMportfoundTextArea.Editable = 'off';
                app.PortfoundLamp.Color = [1,0,0];
                app.InitiateconnectionButton.Enable = 'off';
            else
                app.s = serial(app.SerialInfo.SerialPorts(end),'BAUD',9600);
                app.COMportfoundTextArea.Value = app.SerialInfo.SerialPorts(end);
                app.COMportfoundTextArea.Enable = 'on';
                app.COMportfoundTextArea.Editable = 'off';
                app.PortfoundLamp.Color = [0,1,0];
                app.InitiateconnectionButton.Enable = 'on';
            end
        end

        % Button pushed function: DEBUGBLACKButton
        function DEBUGBLACKButtonPushed(app, event)
            ttx = 'V';
            fwrite(app.s,ttx);
        end

        % Value changed function: BandwidthSlider
        function BandwidthSliderValueChanged(app, event)
            app.Bandwidth = round(app.BandwidthSlider.Value);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            setAutoResize(app, app.UIFigure, true)
            app.InitiateconnectionButton = uibutton(app.UIFigure, 'push');
            app.InitiateconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @InitiateconnectionButtonPushed, true);
            app.InitiateconnectionButton.Text = 'Initiate connection';
            app.TerminateButton = uibutton(app.UIFigure, 'push');
            app.TerminateButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton.Text = 'Terminate';
            app.WavelengthSpinnerLabel = uilabel(app.UIFigure);
            app.WavelengthSpinnerLabel.Text = 'Wavelength';
            app.WavelengthSpinner = uispinner(app.UIFigure);
            app.WavelengthSpinner.ValueChangedFcn = createCallbackFcn(app, @WavelengthSpinnerValueChanged, true);
            app.WavelengthSpinner.Limits = [400 700];
            app.WavelengthSpinner.RoundFractionalValues = 'on';
            app.WavelengthSpinner.ValueDisplayFormat = '%11.3g';
            app.WavelengthSpinner.Value = 400;
            app.SENDButton = uibutton(app.UIFigure, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.SendingLampLabel = uilabel(app.UIFigure);
            app.SendingLampLabel.Text = 'Sending';
            app.SendingLamp = uilamp(app.UIFigure);
            app.COMportfoundTextAreaLabel = uilabel(app.UIFigure);
            app.COMportfoundTextAreaLabel.Text = 'COMport found:';
            app.COMportfoundTextArea = uitextarea(app.UIFigure);
            app.PortfoundLampLabel = uilabel(app.UIFigure);
            app.PortfoundLampLabel.Text = 'Port found';
            app.PortfoundLamp = uilamp(app.UIFigure);
            app.ConnectionStatusLampLabel = uilabel(app.UIFigure);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SentDataTextAreaLabel = uilabel(app.UIFigure);
            app.SentDataTextAreaLabel.Text = 'Sent Data';
            app.SentDataTextArea = uitextarea(app.UIFigure);
            app.DEBUGBLACKButton = uibutton(app.UIFigure, 'push');
            app.DEBUGBLACKButton.ButtonPushedFcn = createCallbackFcn(app, @DEBUGBLACKButtonPushed, true);
            app.DEBUGBLACKButton.Text = 'DEBUG: BLACK';
            app.BandwidthSliderLabel = uilabel(app.UIFigure);
            app.BandwidthSliderLabel.Text = 'Bandwidth';
            app.BandwidthSlider = uislider(app.UIFigure);
            app.BandwidthSlider.Limits = [0 200];
            app.BandwidthSlider.ValueChangedFcn = createCallbackFcn(app, @BandwidthSliderValueChanged, true);
            app.BandwidthSlider.Value = 20;
        end
    end

    methods (Access = public)

        % Construct app
        function app = USB_test()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end